classdef Robot_Arm_Optimization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        StressStrainTablePanel          matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        MainParametersPanel             matlab.ui.container.Panel
        CalculateButton_2               matlab.ui.control.Button
        CroosSectionTypeDropDown        matlab.ui.control.DropDown
        CroosSectionTypeDropDownLabel   matlab.ui.control.Label
        MaterialofArmDropDown           matlab.ui.control.DropDown
        MaterialofArmDropDownLabel      matlab.ui.control.Label
        Maxaccelrationradsec2EditField  matlab.ui.control.NumericEditField
        MaxaccelrationLabel             matlab.ui.control.Label
        LoadMasskgEditField             matlab.ui.control.NumericEditField
        LoadMasskgEditFieldLabel        matlab.ui.control.Label
        HeightcmEditField               matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel          matlab.ui.control.Label
        WidthcmEditField                matlab.ui.control.NumericEditField
        WidthcmEditFieldLabel           matlab.ui.control.Label
        RadiuscmEditField               matlab.ui.control.NumericEditField
        RadiuscmEditFieldLabel          matlab.ui.control.Label
        LengthcmEditField               matlab.ui.control.NumericEditField
        LengthcmEditFieldLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
    end

    
    properties (Access = private)
        DisplayApp % Application to dispaly the final results
    end
    
    properties (Access = public)
        crsec % Description
        Yield
        denisty
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.WidthcmEditField.Enable = "off";
                app.HeightcmEditField.Enable = "off";
                app.RadiuscmEditField.Enable = "off";  
        end

        % Value changed function: CroosSectionTypeDropDown
        function CroosSectionTypeDropDownValueChanged(app, event)
            app.crsec = app.CroosSectionTypeDropDown.Value;
            if app.crsec == "Circular" 
                app.WidthcmEditField.Enable = "off";
                app.HeightcmEditField.Enable = "off";
                app.RadiuscmEditField.Enable = "on";
                app.WidthcmEditField.Value = 0;
                app.HeightcmEditField.Value = 0;
                
            elseif app.crsec == "Rectangualr"
                app.WidthcmEditField.Enable = "on";
                app.HeightcmEditField.Enable = "on";
                app.RadiuscmEditField.Enable = "off";
                app.RadiuscmEditField.Value = 0;

            else 
               app.WidthcmEditField.Enable = "off";
                app.HeightcmEditField.Enable = "off";
                app.RadiuscmEditField.Enable = "off"; 
                  
                
            end
        end

        % Value changed function: RadiuscmEditField
        function RadiuscmEditFieldValueChanged(app, event)
       
            
        end

        % Value changed function: HeightcmEditField
        function HeightcmEditFieldValueChanged(app, event)
        
            
        end

        % Value changed function: WidthcmEditField
        function WidthcmEditFieldValueChanged(app, event)
 
            
        end

        % Value changed function: LengthcmEditField
        function LengthcmEditFieldValueChanged(app, event)
         
            
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            r = app.RadiuscmEditField.Value;
            w = app.WidthcmEditField.Value;
            l = app.LengthcmEditField.Value;
            h = app.HeightcmEditField.Value;
            m = app.LoadMasskgEditField.Value;
            a = app.Maxaccelrationradsec2EditField.Value;
            mat = app.MaterialofArmDropDown.Value;


           app.DisplayApp = display_app(app,r,w,h,l,m,a,mat,app.crsec,app.Yield,app.denisty);


        end

        % Value changed function: MaterialofArmDropDown
        function MaterialofArmDropDownValueChanged(app, event)
            mat = app.MaterialofArmDropDown.Value;
            cast_iron_strain = [0 0.00015 0.00030 0.00045 0.00060 0.00075 0.00090 0.00105 0.00120 0.00135 0.00150 0.00165 0.00180 0.00195 0.00210 0.00225 0.00240 0.00255 0.00270 0.00285 0.00300];
            cast_iron_stress = [0 15 30 45 60 75 90 105 120 135 150 162 174 186 196 205 214 223 232 241 250];

            copper_nickel_strain = [0 0.015 0.030 0.045 0.060 0.075 0.090 0.105 0.120 0.135 0.150 0.165 0.180 0.195 0.210 0.225 0.240 0.255 0.270 0.285 0.300];
            copper_nickel_stress = [0 198.75 225 251.25 270 285 300 312 318 324 330 336 342 348 351.9 354.75 357.6 360.45 363.3 366.15 369];

            brass_strain = [0 0.0335 0.067 0.1005 0.134 0.1675 0.201 0.2345 0.268 0.3015 0.335 0.3685 0.402 0.4355 0.469 0.5025 0.536 0.5695 0.603 0.6365 0.670];
            brass_stress = [0 195.25 228.5 245.25 262 273.5 280.2 286.9 293.6 300.1 302.6 305.1 307.65 310.16 312.68 315.13 316.91 318.68 320.45 322.23 324];

            aluminium_strain = [0 0.006 0.012 0.018 0.024 0.030 0.036 0.042 0.048 0.054 0.060 0.066 0.072 0.078 0.084 0.090 0.096 0.102 0.108 0.114 0.120];
            aluminium_stress = [0 279 286.5 291 295.5 300 302 304 306 308 310 309 308 307 306 305 302 299 296 293 290];

            steel_strain = [0 0.010 0.020 0.030 0.040 0.050 0.060 0.070 0.080 0.090 0.100 0.110 0.120 0.130 0.140 0.150 0.160 0.170 0.180 0.190 0.200];
            steel_stress = [0 316.67 350 356.25 362.5 368.75 375 381.25 387.5 393.75 400 404 408 412 416 420 416 412 408 404 400];

            acrylic_strain = [0 0.002 0.004 0.006 0.008 0.010 0.012 0.014 0.016 0.018 0.020 0.022 0.024 0.026 0.028 0.030 0.032 0.034 0.036 0.038 0.040];
            acrylic_stress = [0 6.6 13.2 19.8 26.4 33 39.8 46.6 53 59 65 69 73 74.4 73.2 72 70.6 69.2 67.8 66.4 65];

            copper_strain = [0 0.015 0.030 0.045 0.060 0.075 0.090 0.105 0.120 0.135 0.150 0.165 0.180 0.195 0.210 0.225 0.240 0.255 0.270 0.285 0.300];
            copper_stress = [0 135 150 165 174 180 186 191 194 197 200 203 206 209 211 212.5 214 215.5 217 218.5 220];

            stainless_steel_strain = [0 0.025 0.050 0.075 0.100 0.125 0.150 0.175 0.200 0.225 0.250 0.275 0.300 0.325 0.350 0.375 0.400 0.425 0.450 0.475 0.500];
            stainless_steel_stress = [0 337.5 400 420 440 460 480 486.67 493.33 500 506.67 513.33 520 511.25 502.5 493.75 485 476.25 467.5 458.75 450];

            tungsten_strain = [0 0.0005 0.0010 0.0015 0.0020 0.0025 0.0030 0.0035 0.0040 0.0045 0.0050 0.0055 0.0060 0.0065 0.0070 0.0075 0.0080 0.0085 0.0090 0.0095 0.0100];
            tungsten_stress = [0 205 410 550 600 625 650 657.5 665 672.5 680 683.33 686.67 690 693.33 696.67 700 687.5 675 662.5 650];

            if mat == "Cast Iron"
              plot(app.UIAxes, cast_iron_strain,cast_iron_stress);
              app.Yield = 130;
              app.denisty = 7.3;
            elseif mat == "Copper nickel"
               plot(app.UIAxes, copper_nickel_strain,copper_nickel_stress); 
              app.Yield = 130;
              app.denisty = 8.49;
            elseif mat == "Brass"
               plot(app.UIAxes, brass_strain,brass_stress);
              app.Yield = 200;
              app.denisty = 8.733;
             elseif mat == "Aluminium"
               plot(app.UIAxes, aluminium_strain,aluminium_stress);
              app.Yield = 241;
              app.denisty = 2.7;
             elseif mat == "Steel"
               plot(app.UIAxes, steel_strain,steel_stress);
              app.Yield = 247;
              app.denisty = 7.58;
             elseif mat == "Acrylic"
               plot(app.UIAxes, acrylic_strain,acrylic_stress); 
              app.Yield = 72;
              app.denisty = 1.16;
             elseif mat == "Copper"
               plot(app.UIAxes, copper_strain,copper_stress);
              app.Yield = 70;
              app.denisty = 8.92;
             elseif mat == "Stainless Steel"
               plot(app.UIAxes, stainless_steel_strain,stainless_steel_stress);
              app.Yield = 275;
              app.denisty = 7.86;
             elseif mat == "Tungsten"
               plot(app.UIAxes, tungsten_strain,tungsten_stress);
              app.Yield = 941;
              app.denisty = 19.25;
            else 
                plot(app.UIAxes , 0, 0)
              app.Yield = 0;
              app.denisty = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.MainParametersPanel = uipanel(app.UIFigure);
            app.MainParametersPanel.Title = 'Main Parameters';
            app.LengthcmEditFieldLabel = uilabel(app.MainParametersPanel);
            app.LengthcmEditFieldLabel.Text = 'Length (cm)';
            app.LengthcmEditField = uieditfield(app.MainParametersPanel, 'numeric');
            app.LengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthcmEditFieldValueChanged, true);
            app.RadiuscmEditFieldLabel = uilabel(app.MainParametersPanel);
            app.RadiuscmEditFieldLabel.Text = 'Radius (cm)';
            app.RadiuscmEditField = uieditfield(app.MainParametersPanel, 'numeric');
            app.RadiuscmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiuscmEditFieldValueChanged, true);
            app.WidthcmEditFieldLabel = uilabel(app.MainParametersPanel);
            app.WidthcmEditFieldLabel.Text = 'Width (cm)';
            app.WidthcmEditField = uieditfield(app.MainParametersPanel, 'numeric');
            app.WidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthcmEditFieldValueChanged, true);
            app.HeightcmEditFieldLabel = uilabel(app.MainParametersPanel);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.MainParametersPanel, 'numeric');
            app.HeightcmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightcmEditFieldValueChanged, true);
            app.LoadMasskgEditFieldLabel = uilabel(app.MainParametersPanel);
            app.LoadMasskgEditFieldLabel.Text = 'Load Mass (kg)';
            app.LoadMasskgEditField = uieditfield(app.MainParametersPanel, 'numeric');
            app.MaxaccelrationLabel = uilabel(app.MainParametersPanel);
            app.MaxaccelrationLabel.Text = {'Max accelration '; '(rad/sec^2)'};
            app.Maxaccelrationradsec2EditField = uieditfield(app.MainParametersPanel, 'numeric');
            app.MaterialofArmDropDownLabel = uilabel(app.MainParametersPanel);
            app.MaterialofArmDropDownLabel.Text = 'Material of Arm';
            app.MaterialofArmDropDown = uidropdown(app.MainParametersPanel);
            app.MaterialofArmDropDown.Items = {'Choose Material', 'Cast Iron', 'Copper nickel', 'Brass', 'Aluminium', 'Steel', 'Acrylic', 'Copper', 'Stainless Steel', 'Tungsten'};
            app.MaterialofArmDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialofArmDropDownValueChanged, true);
            app.MaterialofArmDropDown.Value = 'Choose Material';
            app.CroosSectionTypeDropDownLabel = uilabel(app.MainParametersPanel);
            app.CroosSectionTypeDropDownLabel.Text = 'Croos Section Type';
            app.CroosSectionTypeDropDown = uidropdown(app.MainParametersPanel);
            app.CroosSectionTypeDropDown.Items = {'Choose type', 'Circular', 'Rectangualr'};
            app.CroosSectionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CroosSectionTypeDropDownValueChanged, true);
            app.CroosSectionTypeDropDown.Value = 'Choose type';
            app.CalculateButton_2 = uibutton(app.MainParametersPanel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.StressStrainTablePanel = uipanel(app.UIFigure);
            app.StressStrainTablePanel.Title = 'Stress-Strain Table';
            app.UIAxes = uiaxes(app.StressStrainTablePanel);
            xlabel(app.UIAxes, 'Strain (epsilon)')
            ylabel(app.UIAxes, 'Stress(MPa)')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '610tPYOvGHL._AC_UF894,1000_QL80_.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot_Arm_Optimization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end