classdef display_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OptimizedArmPanel           matlab.ui.container.Panel
        HeightcmEditField           matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel      matlab.ui.control.Label
        MaxStressMPaEditField       matlab.ui.control.NumericEditField
        MaxStressMPaEditFieldLabel  matlab.ui.control.Label
        MassofArmkgEditField        matlab.ui.control.NumericEditField
        MassofArmkgEditFieldLabel   matlab.ui.control.Label
        WidthcmEditField            matlab.ui.control.NumericEditField
        WidthcmEditFieldLabel       matlab.ui.control.Label
        LengthcmEditField           matlab.ui.control.NumericEditField
        LengthcmEditFieldLabel      matlab.ui.control.Label
        RadiuscmEditField           matlab.ui.control.NumericEditField
        RadiuscmEditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, r, w, h, l, m, a, mat, crsec, Yield, density)
                       app.RadiuscmEditField.Editable = 'off';
                       app.LengthcmEditField.Editable = 'off';
                       app.WidthcmEditField.Editable = 'off';
                       app.MassofArmkgEditField.Editable = 'off';
                       app.MaxStressMPaEditField.Editable = 'off';
                       app.HeightcmEditField.Editable = "off";

            while true 
            if crsec == "Circular"
                ml = density*pi * r^2 *l*0.001;
                b_m = ml * 9.81 * l*0.01/2 + m * 9.81 * l*0.01 + ml * (l*0.01/2)^2 * a + m * l*0.01^2 * a;
                i_f = pi * (r*10)^4 /4;
                stress = b_m *r*10000 / i_f;
                app.WidthcmEditField.Enable = "off";
                app.HeightcmEditField.Enable = "off";
                
            elseif crsec == "Rectangualr"
                ml = density*w*l*h*0.001;
                b_m = ml * 9.81 * l*0.01/2 + m * 9.81 * l*0.01 + ml * (l*0.01/2)^2 * a + m * l*0.01^2 * a;
                i_f = w*10 * (h*10)^3 /12;
                stress = b_m * h*10000 /(2 * i_f); 
                

            end
            
            if (stress >= Yield)
                if crsec == "Circular"
                    r = r + r/20;
                app.RadiuscmEditField.Value = r;
                app.HeightcmEditField.Value = 0;
                app.WidthcmEditField.Value = 0;
                    continue;
                elseif crsec == "Rectangualr"
                    w = w + w/20;
                    h = h + h/20;
                    app.RadiuscmEditField.Enable = "off";
                app.RadiuscmEditField.Value = 0;
                app.HeightcmEditField.Value = h;
                app.WidthcmEditField.Value = w;
                    continue;
                end
            end

             if (stress < (Yield -3))
                if crsec == "Circular"
                    r = r - r/100;
                app.RadiuscmEditField.Value = r;
                app.HeightcmEditField.Value = 0;
                app.WidthcmEditField.Value = 0;
                    continue;
                elseif crsec == "Rectangualr"
                    w = w - w/100;
                    h = h - h/100;
                    app.RadiuscmEditField.Enable = "off";
                app.RadiuscmEditField.Value = 0;
                app.HeightcmEditField.Value = h;
                app.WidthcmEditField.Value = w;
                    continue;                 
             
               end

             end
             break;
            end
    
    app.LengthcmEditField.Value = l;
    app.MassofArmkgEditField.Value = ml;
    app.MaxStressMPaEditField.Value = stress;
    
        end

        % Value changed function: RadiuscmEditField
        function RadiuscmEditFieldValueChanged(app, event)
        
            
        end

        % Value changed function: LengthcmEditField
        function LengthcmEditFieldValueChanged(app, event)
        
            
        end

        % Value changed function: WidthcmEditField
        function WidthcmEditFieldValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptimizedArmPanel = uipanel(app.UIFigure);
            app.OptimizedArmPanel.Title = 'Optimized Arm';
            app.RadiuscmEditFieldLabel = uilabel(app.OptimizedArmPanel);
            app.RadiuscmEditFieldLabel.Text = 'Radius(cm)';
            app.RadiuscmEditField = uieditfield(app.OptimizedArmPanel, 'numeric');
            app.RadiuscmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiuscmEditFieldValueChanged, true);
            app.LengthcmEditFieldLabel = uilabel(app.OptimizedArmPanel);
            app.LengthcmEditFieldLabel.Text = 'Length(cm)';
            app.LengthcmEditField = uieditfield(app.OptimizedArmPanel, 'numeric');
            app.LengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthcmEditFieldValueChanged, true);
            app.WidthcmEditFieldLabel = uilabel(app.OptimizedArmPanel);
            app.WidthcmEditFieldLabel.Text = 'Width(cm)';
            app.WidthcmEditField = uieditfield(app.OptimizedArmPanel, 'numeric');
            app.WidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthcmEditFieldValueChanged, true);
            app.MassofArmkgEditFieldLabel = uilabel(app.OptimizedArmPanel);
            app.MassofArmkgEditFieldLabel.Text = 'Mass of Arm(kg)';
            app.MassofArmkgEditField = uieditfield(app.OptimizedArmPanel, 'numeric');
            app.MaxStressMPaEditFieldLabel = uilabel(app.OptimizedArmPanel);
            app.MaxStressMPaEditFieldLabel.Text = 'Max Stress (MPa)';
            app.MaxStressMPaEditField = uieditfield(app.OptimizedArmPanel, 'numeric');
            app.HeightcmEditFieldLabel = uilabel(app.OptimizedArmPanel);
            app.HeightcmEditFieldLabel.Text = 'Height(cm)';
            app.HeightcmEditField = uieditfield(app.OptimizedArmPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = display_app(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end